classdef interfaceDesign < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                    matlab.ui.Figure
        RatioDropDown_2             matlab.ui.control.DropDown
        RatioDropDown_2Label        matlab.ui.control.Label
        TimeLimitsDropDown_2        matlab.ui.control.DropDown
        TimeLimitsDropDown_2Label   matlab.ui.control.Label
        DisplayFinalResultsButton   matlab.ui.control.Button
        TimeLimitsDropDown          matlab.ui.control.DropDown
        TimeLimitsDropDownLabel     matlab.ui.control.Label
        TaskDropDown                matlab.ui.control.DropDown
        TaskDropDownLabel           matlab.ui.control.Label
        ClearGridButton             matlab.ui.control.Button
        UITable                     matlab.ui.control.Table
        StartexperimentButton_2     matlab.ui.control.Button
        RatioDropDown               matlab.ui.control.DropDown
        RatioDropDownLabel          matlab.ui.control.Label
        DisplayPretestResultButton  matlab.ui.control.Button
        IDTextArea                  matlab.ui.control.TextArea
        IDTextAreaLabel             matlab.ui.control.Label
    end

    
    properties (Access = private)

        SubIDrow = {} % Description
        IDs = {}
        % Ratio = {}
        T = table();% Description
        Stop = 0;
    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Value changed function: IDTextArea
        function IDTextAreaValueChanged(app, event)

            
        end

        % Callback function
        function PretestCheckBoxValueChanged(app, event)
            pretest = app.PretestCheckBox.Value;
            
        end

        % Button pushed function: DisplayPretestResultButton
        function DisplayPretestResultButtonPushed(app, event)
            task = app.TaskDropDown.Value;
            if task == "pretest"
                cd 'C:\Users\AOE\Desktop\time_is_money\session_1_dots_pretest\analysis'
                %pretest=1;
                subID = char(app.IDTextArea.Value);
                analyze_SAT_Color_pretest(subID,1)
                %Sratio= char(app.RatioDropDown.Value);
                
            end
        end

        % Drop down opening function: RatioDropDown
        function RatioDropDownOpening(app, event)
            
        end

        % Button pushed function: StartexperimentButton_2
        function StartexperimentButton_2Pushed(app, event)
            cd 'C:\Users\AOE\Desktop\Time_is_money\session_1_dots_pretest\exp'

            ratio = app.RatioDropDown.Value;
            ratio2 = app.RatioDropDown_2.Value;
            timelimits = app.TimeLimitsDropDown.Value;
            timelimits2 = app.TimeLimitsDropDown_2.Value;
            
            task = app.TaskDropDown.Value;
            % iDs="";
            % subID9row = "";
            
            if task == "task 1"
                cd 'C:\Users\AOE\Desktop\Time_is_money\session_2_dots_test\exp'
                pretest=0;
                subID = char(app.IDTextArea.Value);
                cd inputs\
                try
                    createInputs_SAT_Color_v2(subID,pretest,ratio,ratio2,timelimits,timelimits2);
                catch
                    disp('careful task 1 already run once')
                end
                % app.Ratio = [app.Ratio;ratio];
                app.T = table(app.SubIDrow,app.IDs);
                app.UITable.Data = app.T{:,:};
                % app.Ratio=ratioRow;
                cd ..
                for i = 1:61
                    app.Stop = run_SAT_ColorPenalty(subID,i,pretest);
                    if app.Stop
                        break;
                    end
                end
            elseif task == "task 2"
                app.Stop = 0;
                cd 'C:\Users\AOE\Desktop\Time_is_money\session_2_dots_test\exp'
                subID = char(app.IDTextArea.Value);
                pretest=0;
                for i = 1:61
                    app.Stop = run_SAT_ColorPenalty2(subID,i,pretest);
                    if app.Stop
                        break;
                    end
                end
            elseif task == "pretest"
                cd 'C:\Users\AOE\Desktop\Time_is_money\session_1_dots_pretest\exp'
                pretest=1;
                subID = char(app.IDTextArea.Value);
                % app.Ratio = {};
                % ratio=[""];

                
                app.IDs = [app.IDs;subID];

                app.SubIDrow= [app.SubIDrow;['Candidate ' num2str(length(app.SubIDrow)+1)]];
                % app.SubIDrow = [app.SubIDrow;iDs];
                
                % app.T = table(app.IDs,app.SubIDrow,ratioRow);
                
                app.UITable.Data = app.T{:,:};
                % app.UITable.ColumnName = app.T.Properties.VariableNames;
                % app.UITable.RowName = app.T.Properties.RowNames;
                % app.IDs=iDs;
                % app.SubIDrow=subIDrow;
                %Sratio= char(app.RatioDropDown.Value);
                cd inputs\
                createInputs_SAT_Color_v2(subID,pretest);
                cd ..
                %run_SAT_ColorPenalty(subID,2,pretest);
                for i = 1:9
                    run_SAT_ColorPenalty(subID,i,pretest);
                end
                %run_SAT_ColorPenalty(subID,9,pretest);
            end

        end

        % Cell edit callback: UITable
        function UITableCellEdit(app, event)
             indices = event.Indices;
             newData = event.NewData;
        end

        % Button pushed function: ClearGridButton
        function ClearGridButtonPushed(app, event)
            app.SubIDrow = {}; % Description
            app.IDs = {};
            % app.Ratio = {};
            iDs=[];
            subIDrow=[];
            % ratio=[];

            app.T = table(iDs,subIDrow);
            app.UITable.Data = app.T{:,:};
            % app.UITable.ColumnName = app.T.Properties.VariableNames;
            % app.UITable.RowName = app.T.Properties.RowNames;
        end

        % Value changed function: TaskDropDown
        function TaskDropDownValueChanged(app, event)
            value = app.TaskDropDown.Value;
            
        end

        % Value changed function: TimeLimitsDropDown
        function TimeLimitsDropDownValueChanged(app, event)
            value = app.TimeLimitsDropDown.Value;
            
        end

        % Button pushed function: DisplayFinalResultsButton
        function DisplayFinalResultsButtonPushed(app, event)
            cd 'C:\Users\AOE\Desktop\time_is_money\session_2_dots_test\exp'
            %pretest=1;
            subID = char(app.IDTextArea.Value);
            analyze_results(subID)
            %Sratio= char(app.RatioDropDown.Value);
                

        end

        % Value changed function: RatioDropDown_2
        function RatioDropDown_2ValueChanged(app, event)
            value = app.RatioDropDown_2.Value;
            
        end

        % Value changed function: TimeLimitsDropDown_2
        function TimeLimitsDropDown_2ValueChanged(app, event)
            value = app.TimeLimitsDropDown_2.Value;
            
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.IDTextAreaLabel = uilabel(app.UIFigure);
            app.IDTextAreaLabel.Text = 'ID:';
            app.IDTextArea = uitextarea(app.UIFigure);
            app.IDTextArea.ValueChangedFcn = createCallbackFcn(app, @IDTextAreaValueChanged, true);
            app.IDTextArea.Tag = 'ID';
            app.DisplayPretestResultButton = uibutton(app.UIFigure, 'push');
            app.DisplayPretestResultButton.ButtonPushedFcn = createCallbackFcn(app, @DisplayPretestResultButtonPushed, true);
            app.DisplayPretestResultButton.Text = 'Display Pretest Result';
            app.RatioDropDownLabel = uilabel(app.UIFigure);
            app.RatioDropDownLabel.Text = 'Ratio';
            app.RatioDropDown = uidropdown(app.UIFigure);
            app.RatioDropDown.Items = {'[60 40]', '[57 43]', '[54 46]', '[52 48]'};
            app.RatioDropDown.DropDownOpeningFcn = createCallbackFcn(app, @RatioDropDownOpening, true);
            app.RatioDropDown.Value = '[60 40]';
            app.StartexperimentButton_2 = uibutton(app.UIFigure, 'push');
            app.StartexperimentButton_2.ButtonPushedFcn = createCallbackFcn(app, @StartexperimentButton_2Pushed, true);
            app.StartexperimentButton_2.Text = 'Start experiment';
            app.UITable = uitable(app.UIFigure);
            app.UITable.ColumnName = {'Candidate number'; 'ID'; 'Ratio'};
            app.UITable.RowName = {};
            app.UITable.CellEditCallback = createCallbackFcn(app, @UITableCellEdit, true);
            app.ClearGridButton = uibutton(app.UIFigure, 'push');
            app.ClearGridButton.ButtonPushedFcn = createCallbackFcn(app, @ClearGridButtonPushed, true);
            app.ClearGridButton.Text = 'ClearGrid';
            app.TaskDropDownLabel = uilabel(app.UIFigure);
            app.TaskDropDownLabel.Text = 'Task';
            app.TaskDropDown = uidropdown(app.UIFigure);
            app.TaskDropDown.Items = {'pretest', 'task 1', 'task 2'};
            app.TaskDropDown.ValueChangedFcn = createCallbackFcn(app, @TaskDropDownValueChanged, true);
            app.TaskDropDown.Placeholder = 'pretest';
            app.TaskDropDown.Value = 'pretest';
            app.TimeLimitsDropDownLabel = uilabel(app.UIFigure);
            app.TimeLimitsDropDownLabel.Text = 'TimeLimits';
            app.TimeLimitsDropDown = uidropdown(app.UIFigure);
            app.TimeLimitsDropDown.Items = {'[1.0]', '[1.5] ', '[2.0] ', '[2.5]'};
            app.TimeLimitsDropDown.ValueChangedFcn = createCallbackFcn(app, @TimeLimitsDropDownValueChanged, true);
            app.TimeLimitsDropDown.Value = '[1.0]';
            app.DisplayFinalResultsButton = uibutton(app.UIFigure, 'push');
            app.DisplayFinalResultsButton.ButtonPushedFcn = createCallbackFcn(app, @DisplayFinalResultsButtonPushed, true);
            app.DisplayFinalResultsButton.Text = 'Display Final Results';
            app.TimeLimitsDropDown_2Label = uilabel(app.UIFigure);
            app.TimeLimitsDropDown_2Label.Text = 'TimeLimits';
            app.TimeLimitsDropDown_2 = uidropdown(app.UIFigure);
            app.TimeLimitsDropDown_2.Items = {'[1.0]', '[1.5] ', '[2.0] ', '[2.5]'};
            app.TimeLimitsDropDown_2.ValueChangedFcn = createCallbackFcn(app, @TimeLimitsDropDown_2ValueChanged, true);
            app.TimeLimitsDropDown_2.Value = '[1.0]';
            app.RatioDropDown_2Label = uilabel(app.UIFigure);
            app.RatioDropDown_2Label.Text = 'Ratio';
            app.RatioDropDown_2 = uidropdown(app.UIFigure);
            app.RatioDropDown_2.Items = {'[60 40]', '[57 43]', '[54 46]', '[52 48]'};
            app.RatioDropDown_2.ValueChangedFcn = createCallbackFcn(app, @RatioDropDown_2ValueChanged, true);
            app.RatioDropDown_2.Value = '[60 40]';
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = interfaceDesign

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end